classdef rOMT_tracker_v03 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        sFrame                        matlab.ui.control.Slider
        FrameSliderLabel              matlab.ui.control.Label
        dbVisualize                   matlab.ui.control.DropDown
        VisualizeDropDownLabel        matlab.ui.control.Label
        pbPreprocess                  matlab.ui.control.Button
        pbBrowse                      matlab.ui.control.Button
        eOutputFolder                 matlab.ui.control.EditField
        OutputFolderEditFieldLabel    matlab.ui.control.Label
        pbLoadVolume                  matlab.ui.control.Button
        sFmax                         matlab.ui.control.Slider
        P4Label_2                     matlab.ui.control.Label
        sFmin                         matlab.ui.control.Slider
        P4Label                       matlab.ui.control.Label
        pbLoadMask                    matlab.ui.control.Button
        sParameter2                   matlab.ui.control.Slider
        Parameter2Label               matlab.ui.control.Label
        sParameter1                   matlab.ui.control.Slider
        Parameter1Label               matlab.ui.control.Label
        pbProcess                     matlab.ui.control.Button
        sAlpha                        matlab.ui.control.Slider
        AlphaLabel                    matlab.ui.control.Label
        FlowcalculationDropDown       matlab.ui.control.DropDown
        FlowcalculationDropDownLabel  matlab.ui.control.Label
        sP2                           matlab.ui.control.Slider
        P2Label                       matlab.ui.control.Label
        sP1                           matlab.ui.control.Slider
        P1Label                       matlab.ui.control.Label
        dbPreprocessing               matlab.ui.control.DropDown
        PreprocessingDropDownLabel    matlab.ui.control.Label
        dbFiltering                   matlab.ui.control.DropDown
        FilteringDropDownLabel        matlab.ui.control.Label
        ContrastLabel                 matlab.ui.control.Label
        sMax                          matlab.ui.control.Slider
        sMin                          matlab.ui.control.Slider
        dbOrientation                 matlab.ui.control.DropDown
        OrientationDropDownLabel      matlab.ui.control.Label
        sT                            matlab.ui.control.Slider
        sZ                            matlab.ui.control.Slider
        labelT                        matlab.ui.control.Label
        labelZ                        matlab.ui.control.Label
        Axes2                         matlab.ui.control.UIAxes
        Axes1                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        V % MRI volume
        Vpreprocessed % preprocessed volume
        Vpath % volume path
        maskPath %ROI mask path
        jsonFileName = 'template';

    end

    methods (Access = private)

        function showimage(app)
            contrastMin = app.sMin.Value;
            contrastMax = max(contrastMin,app.sMax.Value);
            if contrastMax==contrastMin
                app.sMax.Value = contrastMax;
            end
            orientation = app.dbOrientation.Value;
            T = app.sT.Value;

            if strcmp(app.dbFiltering.Value,'None')
                if strcmp(orientation,'XY')
                    Z = app.sZ.Value;
                    imshow(app.V(:,:,Z,T),[app.sMin.Value,app.sMax.Value],'Parent', app.Axes1);
                    imshow(app.Vpreprocessed(:,:,Z,T),[app.sMin.Value,app.sMax.Value],'Parent', app.Axes2);
                elseif strcmp(orientation,'YZ')
                    X = app.sZ.Value;
                    imshow(squeeze(app.V(X,:,:,T)),[app.sMin.Value,app.sMax.Value],'Parent', app.Axes1);
                    imshow(squeeze(app.Vpreprocessed(X,:,:,T)),[app.sMin.Value,app.sMax.Value],'Parent', app.Axes2);
                else
                    Y = app.sZ.Value;
                    imshow(squeeze(app.V(:,Y,:,T)),[app.sMin.Value,app.sMax.Value],'Parent', app.Axes1);
                    imshow(squeeze(app.Vpreprocessed(:,Y,:,T)),[app.sMin.Value,app.sMax.Value],'Parent', app.Axes2);
                end
            end
            if strcmp(app.dbFiltering.Value,'Median')
                if strcmp(orientation,'XY')
                    Z = app.sZ.Value;
                    imshow(medfilt2(app.V(:,:,Z,T),round([2*app.sParameter1.Value+1,2*app.sParameter2.Value+1])),...
                        [app.sMin.Value,app.sMax.Value],'Parent', app.Axes1);
                    imshow(app.Vpreprocessed(:,:,Z,T),[app.sMin.Value,app.sMax.Value],'Parent', app.Axes2);
                elseif strcmp(orientation,'YZ')
                    X = app.sZ.Value;
                    imshow(medfilt2(squeeze(app.V(X,:,:,T)),round([2*app.sParameter1.Value+1,2*app.sParameter2.Value+1])),...
                        [app.sMin.Value,app.sMax.Value],'Parent', app.Axes1);
                    imshow(squeeze(app.Vpreprocessed(X,:,:,T)),[app.sMin.Value,app.sMax.Value],'Parent', app.Axes2);
                else
                    Y = app.sZ.Value;
                    imshow(medfilt2(squeeze(app.V(:,Y,:,T)),round([2*app.sParameter1.Value+1,2*app.sParameter2.Value+1])),...
                        [app.sMin.Value,app.sMax.Value],'Parent', app.Axes1);
                    imshow(squeeze(app.Vpreprocessed(:,Y,:,T)),[app.sMin.Value,app.sMax.Value],'Parent', app.Axes2);
                end
            end
            if strcmp(app.dbFiltering.Value,'Adjust')
                if strcmp(orientation,'XY')
                    Z = app.sZ.Value;
                    I = app.V(:,:,Z,T);
                    mn = min(I,[],'all');
                    rn = max(I,[],'all')-mn;
                    I = (I-mn)/rn;
                    imshow(rn*imadjust(I,[],[],app.sParameter1.Value)+mn,[app.sMin.Value,app.sMax.Value],'Parent', app.Axes1);
                    imshow(app.Vpreprocessed(:,:,Z,T),[app.sMin.Value,app.sMax.Value],'Parent', app.Axes2);
                elseif strcmp(orientation,'YZ')
                    X = app.sZ.Value;
                    I = squeeze(app.V(X,:,:,T));
                    mn = min(I,[],'all');
                    rn = max(I,[],'all')-mn;
                    I = (I-mn)/rn;
                    imshow(rn*imadjust(I,[],[],app.sParameter1.Value)+mn,[app.sMin.Value,app.sMax.Value],'Parent', app.Axes1);
                    imshow(squeeze(app.Vpreprocessed(X,:,:,T)),[app.sMin.Value,app.sMax.Value],'Parent', app.Axes2);
                else
                    Y = app.sZ.Value;
                    I = squeeze(app.V(:,Y,:,T));
                    mn = min(I,[],'all');
                    rn = max(I,[],'all')-mn;
                    I = (I-mn)/rn;
                    imshow(rn*imadjust(I,[],[],app.sParameter1.Value)+mn,[app.sMin.Value,app.sMax.Value],'Parent', app.Axes1);
                    imshow(squeeze(app.Vpreprocessed(:,Y,:,T)),[app.sMin.Value,app.sMax.Value],'Parent', app.Axes2);
                end

            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('./Sensitivities', './analyzeFlows', genpath('./utilities'))

        end

        % Value changed function: sZ
        function sZValueChanged(app, event)
            app.sZ.Value = round(app.sZ.Value);
            orientation = app.dbOrientation.Value;
            if strcmp(orientation,'XY')
                app.labelZ.Text = "Z="+num2str(app.sZ.Value);
            elseif strcmp(orientation,'YZ')
                app.labelZ.Text = "X="+num2str(app.sZ.Value);
            else
                app.labelZ.Text = "Y="+num2str(app.sZ.Value);
            end


            showimage(app)
        end

        % Value changed function: sT
        function sTValueChanged(app, event)
            app.sT.Value = round(app.sT.Value);
            app.labelT.Text = "T="+num2str(app.sT.Value);
            showimage(app)

        end

        % Value changed function: sMin
        function sMinValueChanged(app, event)
            showimage(app)
        end

        % Value changed function: sMax
        function sMaxValueChanged(app, event)
            showimage(app)
        end

        % Value changed function: dbFiltering
        function dbFilteringValueChanged(app, event)
            if strcmp(app.dbFiltering.Value,"Adjust")
                app.sParameter1.Limits = [0,5];
                app.sParameter1.Value = 1;
            elseif strcmp(app.dbFiltering.Value,"Median")
                app.sParameter1.Limits = [0,5];
                app.sParameter1.Value = 0;
                app.sParameter2.Limits = [0,5];
                app.sParameter2.Value = 0;

            end
            showimage(app)
        end

        % Value changed function: sParameter1
        function sParameter1ValueChanged(app, event)
            showimage(app)

        end

        % Value changed function: sParameter2
        function sParameter2ValueChanged(app, event)
            showimage(app)

        end

        % Value changed function: dbOrientation
        function dbOrientationValueChanged(app, event)
            showimage(app)

        end

        % Button pushed function: pbLoadMask
        function pbLoadMaskButtonPushed(app, event)
            [file,path] = uigetfile("*.nii;*.nii.gz","Load mask");
            if ~file
                error("No mask is available");
            else
                app.maskPath = fullfile(path,file);
            end

        end

        % Button pushed function: pbProcess
        function pbProcessButtonPushed(app, event)
            %% preprocessing of V
            Vprocessed = app.Vpreprocessed;

            %% save volume and mask
            niftiwrite(Vprocessed,'./utilities/processedVolume.nii')
            Vmask = niftiread(app.maskPath);
            niftiwrite(Vmask,'./utilities/MASK.nii')


            %% reading sliders and parameters
            if strcmp(app.FlowcalculationDropDown.Value,'rOMT')
                obj.addSource = 0;
            else
                obj.addSource = 1;
            end

            %% write parameters into a json file, json file name is jsonFileName+'.json'
            obj.pathMask = './utilities/MASK.nii';
            obj.pathData = './utilities/processedVolume.nii';
            obj.alpha = app.sAlpha.Value;
            obj.timeInitial = app.sFmin.Value;
            obj.timeFinal = app.sFmax.Value;
            obj.pathOutputFolder = strrep(app.eOutputFolder.Value,'\','/');
            obj.isDataPacked = 1;
            encodedJson = jsonencode(obj,PrettyPrint=true);
            fid=fopen('template.json','w');
            fprintf(fid, encodedJson);
            fclose(fid);

            %% run OMT
            runRegularizedOMT(app.jsonFileName);
            % process and visualize results
            runPostprocessing(app.jsonFileName);


        end

        % Button pushed function: pbLoadVolume
        function pbLoadVolumeButtonPushed(app, event)
            [file,path] = uigetfile("*.nii;*.nii.gz","Load volume");
            app.Vpath = fullfile(path,file);
            app.V = double(niftiread(app.Vpath));
            app.Vpreprocessed = app.V;
            [~,~,nz,nt] = size(app.V);
            app.sFmin.Limits = [1 nt];
            app.sFmin.Value = 1;
            app.sFmax.Limits = [1 nt];
            app.sFmax.Value = nt;
            app.sMin.Limits = [min(app.V,[],'all'),max(app.V,[],'all')];
            app.sMin.Value = min(app.V,[],'all');
            app.sMax.Limits = [min(app.V,[],'all'),max(app.V,[],'all')];
            app.sMax.Value = max(app.V,[],'all');
            app.sZ.Limits = [1 nz];
            app.sT.Limits = [1 nt];
            app.dbOrientation.Value = 'XY';
            showimage(app)
        end

        % Button pushed function: pbBrowse
        function pbBrowseButtonPushed(app, event)
            app.eOutputFolder.Value = uigetdir;
        end

        % Value changed function: sFmin
        function sFminValueChanged(app, event)
            app.sFmin.Value = round(app.sFmin.Value);

        end

        % Value changed function: sFmax
        function sFmaxValueChanged(app, event)
            app.sFmax.Value = round(app.sFmax.Value);

        end

        % Button pushed function: pbPreprocess
        function pbPreprocessButtonPushed(app, event)
            if strcmp(app.dbPreprocessing.Value,"NLM")
                for tm=1:size(app.V,4)
                    disp(tm)
                    app.Vpreprocessed(:,:,:,tm) = nonlocalMeansDenoise(app.V(:,:,:,tm), 1, 1, app.sP1.Value/100, round(app.sP2.Value), 0 );
                end
            elseif strcmp(app.dbPreprocessing.Value,"Wavelets")
                [nx,ny,~,nt] = size(app.V);
                V1 = app.V(:,:,:,1);
                V1(:,:,:,2) = app.V(:,:,:,1);
                V1(:,:,:,3:nt+2) = app.V;
                V1(:,:,:,end+1) = app.V(:,:,:,end);
                V1(:,:,:,end+1) = app.V(:,:,:,end);
                Vout = zeros(size(V1));
                for tm=3:nt
                    disp(tm)
                    for depth=1:size(V1,3)
                        B = padarray(squeeze(V1(:,:,depth,tm-2:tm+2)),[2^nextpow2(nx)-nx, 2^nextpow2(ny)-ny,0],'replicate','post');
                        tmp = waveletMultiFrame(B,'k', app.sP1.Value/50, 'p', app.sP2.Value/50, 'maxLevel', 5, 'weightMode', 4, 'basis', 'dualTree');
                        Vout(:,:,depth,tm) = tmp(1:nx,1:ny);
                    end
                end
                app.Vpreprocessed = Vout(:,:,:,3:end-2);

            elseif strcmp(app.dbPreprocessing.Value,"KAPPA5")
                app.Vpreprocessed = affine_diffusion_4d(app.V,app.sP1.Value/100,app.sP2.Value/100,1,1,'replicate');
                % app.Vpreprocessed = pyrunfile("affine.py","z",array=app.V,t_total=app.sP1.Value/100,delta_t=app.sP2.Value/100);
            else
                app.Vpreprocessed = app.V;
            end
            showimage(app)


        end

        % Value changed function: dbPreprocessing
        function dbPreprocessingValueChanged(app, event)
            if strcmp(app.dbPreprocessing.Value,"NLM")
                app.sP1.Value = 30;
                app.sP2.Value = 10;
            elseif strcmp(app.dbPreprocessing.Value,"Wavelets")
                app.sP1.Value = 55;
                app.sP2.Value = 75;
            elseif strcmp(app.dbPreprocessing.Value,"KAPPA5")
                app.sP1.Value = 50;
                app.sP2.Value = 2;
            else
                app.sP1.Value = 0;
                app.sP2.Value = 0;

            end
        end

        % Value changed function: dbVisualize
        function dbVisualizeValueChanged(app, event)
            % try
                if strcmp(app.dbVisualize.Value,"Preprocessed")
                    cla(app.Axes2,'reset')
                    hold(app.Axes2,"off")
                    showimage(app)
                elseif strcmp(app.dbVisualize.Value,"Speed")
                    configuration = Configuration3D(app.jsonFileName);
                    load(sprintf('%s/pathlines.mat',configuration.pathOutput));
                    %% Speed-lines
                    cla(app.Axes2,'reset')
                    hold(app.Axes2,"off")
                    spdSet = cell2mat(obj.speedInROI');
                    maxset = maxk(spdSet(:),round(0.05*length(spdSet(:))));
                    spdmax = maxset(end);
                    axis(app.Axes2)
                    Num = 100; % the larger, the more intervals in colors

                    SL2 = obj.positionInROI;
                    SL_spd = obj.speedInROI;
                    nSL = length(SL2);
                    colors = jet(Num);
                    if isempty(configuration.mask)
                        configuration.mask = Mask(configuration.pathMask,configuration.isMaskFilled,configuration.xRange,configuration.yRange,configuration.zRange);
                        if configuration.do_resize
                            configuration.mask = configuration.mask.resize(configuration.sizeFactor);
                        end

                        if configuration.dilate>0
                            configuration.mask = configuration.mask.dilate(configuration.dilate,configuration.dilateDim);
                        end
                    end
                    for ind = 1:10:nSL
                        SL_tmp = SL2{ind};
                        SL_spd_tmp = SL_spd{ind};
                        SL_spd_tmp(SL_spd_tmp>spdmax) = spdmax;
                        SL_spd_rk = round(SL_spd_tmp/spdmax*Num);
                        SL_spd_rk(SL_spd_rk<1) = 1;
                        hlines = patch(app.Axes2,[SL_tmp(:,2);NaN],[SL_tmp(:,1);NaN],[SL_tmp(:,3);NaN],[1,1,1]);
                        set(hlines,'FaceVertexCData',[colors(SL_spd_rk,:);colors(SL_spd_rk(end),:)],'EdgeColor','flat','FaceColor','none');
                    end
                    %
                    hold(app.Axes2,"on")
                    [x, y, z] = meshgrid(1:configuration.trueSize(2), 1:configuration.trueSize(1), 1:configuration.trueSize(3));
                    mskfv = isosurface(x,y,z,configuration.mask.contents,0.5);
                    mskp = patch(app.Axes2,mskfv);
                    mskp.FaceColor = [.37,.37,.37];
                    mskp.FaceAlpha= 0.1;
                    mskp.EdgeColor = [.37,.37,.37];
                    mskp.EdgeAlpha= 0;
                    view(app.Axes2,[242.1011   14.4475])
                    xticks(app.Axes2,0:5:configuration.trueSize(1))
                    yticks(app.Axes2,0:5:configuration.trueSize(2))
                    zticks(app.Axes2,0:5:configuration.trueSize(3));
                    ax = app.Axes2; ax.FontSize = 10;
                    xlabel(app.Axes2,'x-axis','FontSize',18)
                    ylabel(app.Axes2,'y-axis','FontSize',18)
                    zlabel(app.Axes2,'z-axis','FontSize',18)
                    set(get(app.Axes2,'YLabel'),'Rotation',10);%xtickangle(20);
                    set(get(app.Axes2,'XLabel'),'Rotation',-30,'VerticalAlignment','middle');
                    set(app.Axes2,'Color',[1,1,1])
                    % set(gcf,'unit','normalized','position',[0.5152 0.2790 0.2943 0.3605],'Color',[1,1,1]) 
                    % set(app.UIFigure, 'InvertHardcopy', 'off')
                    grid(app.Axes2,"on")
                    axis(app.Axes2,'image');
                    xlim(app.Axes2,[0 configuration.trueSize(1)])
                    ylim(app.Axes2,[0 configuration.trueSize(2)])
                    zlim(app.Axes2,[0 configuration.trueSize(3)])
                    colormap(app.Axes2,'jet') 
                    grid(app.Axes2,"on")

                    cb = colorbar(app.Axes2);
                    cb.Ticks = linspace(0, 1, 6);
                    cb.TickLabels = num2cell(round((0:spdmax/5:spdmax)*100)/100);
                    cb.FontSize = 8;
                    cb.Label.String = 'speed (a.u.)';
                    %text(1,-3,15,'speed (a.u.)','Rotation',90,'FontSize',25);
                    title(app.Axes2,'speed-lines')
                    xlim(app.Axes2,[0 configuration.trueSize(1)])
                    ylim(app.Axes2,[0 configuration.trueSize(2)])
                    zlim(app.Axes2,[0 configuration.trueSize(3)])
                elseif strcmp(app.dbVisualize.Value,"Path lines")
                    configuration = Configuration3D(app.jsonFileName);
                    %% pathlines
                    load(sprintf('%s/pathlines.mat',configuration.pathOutput));
                    cla(app.Axes2,'reset')
                    hold(app.Axes2,"off")

                    axis(app.Axes2)
                    SL2 = obj.positionInROI;
                    nSL = length(SL2);
                    %colors = jet(round(max(PATH.displen)));
                    if isempty(configuration.mask)
                        configuration.mask = Mask(configuration.pathMask,configuration.isMaskFilled,configuration.xRange,configuration.yRange,configuration.zRange);
                        if configuration.do_resize
                            configuration.mask = configuration.mask.resize(configuration.sizeFactor);
                        end

                        if configuration.dilate>0
                            configuration.mask = configuration.mask.dilate(configuration.dilate,configuration.dilateDim);
                        end
                    end
                    for ind = 1:10:nSL
                        SL_tmp = SL2{ind};
                        colors = jet(size(SL_tmp,1));
                        hlines = patch(app.Axes2,[SL_tmp(:,2);NaN],[SL_tmp(:,1);NaN],[SL_tmp(:,3);NaN],[1,1,1]);
                        %set(hlines,'EdgeColor',colors(round(PATH.displen(ind)),:));
                        set(hlines,'FaceVertexCData',[colors;colors(end,:)],'EdgeColor','flat','FaceColor','none');
                    end
                    %
                    hold(app.Axes2,"on")
                    [x, y, z] = meshgrid(1:configuration.trueSize(2), 1:configuration.trueSize(1), 1:configuration.trueSize(3));
                    mskfv = isosurface(x,y,z,configuration.mask.contents,0.5);
                    mskp = patch(app.Axes2,mskfv);
                    mskp.FaceColor = [.37,.37,.37];
                    mskp.FaceAlpha= 0.1;
                    mskp.EdgeColor = [.37,.37,.37];
                    mskp.EdgeAlpha= 0;
                    axis(app.Axes2,"image")
                    colormap(app.Axes2,'jet');
                    view(app.Axes2,[242.1011   14.4475])
                    xticks(app.Axes2,0:5:configuration.trueSize(1))
                    yticks(app.Axes2,0:5:configuration.trueSize(2))
                    zticks(app.Axes2,0:5:configuration.trueSize(3));
                    ax = app.Axes2; ax.FontSize = 10;
                    xlabel(app.Axes2,'x-axis','FontSize',18)
                    ylabel(app.Axes2,'y-axis','FontSize',18)
                    zlabel(app.Axes2,'z-axis','FontSize',18)
                    set(get(app.Axes2,'YLabel'),'Rotation',10);%xtickangle(20);
                    set(get(app.Axes2,'XLabel'),'Rotation',-30,'VerticalAlignment','middle');
                    set(app.Axes2,'Color',[1,1,1])
                    %set(gcf,'unit','normalized','position',[0.5152 0.2790 0.2943 0.3605],'Color',[1,1,1]), set(gcf, 'InvertHardcopy', 'off')
                    grid(app.Axes2,"on")
                    xlim(app.Axes2,[0 configuration.trueSize(1)])
                    ylim(app.Axes2,[0 configuration.trueSize(2)])
                    zlim(app.Axes2,[0 configuration.trueSize(3)])

                    cb = colorbar(app.Axes2);
                    set(cb,'YTick',[0,1])
                    cb.TickLabels = {'start','end'};
                    % text(1,-3,53,'end','Rotation',90,'FontSize',25);
                    % text(1,-3,-1,'start','Rotation',90,'FontSize',25);
                    title(app.Axes2,'pathlines')
                elseif strcmp(app.dbVisualize.Value,"Peclet numbers")
                    configuration = Configuration3D(app.jsonFileName);
                    load(sprintf('%s/pathlines.mat',configuration.pathOutput));
                    %% pelines
                    cla(app.Axes2,'reset')
                    hold(app.Axes2,"off")

                    peSet = cell2mat(obj.PeInROI');
                    maxset = maxk(peSet(:),round(0.05*length(peSet(:))));
                    Pemax = maxset(end);
                    axis(app.Axes2)
                    %Pemax = 1000;
                    Num = 100; % the larger, the more intervals in colors

                    SL2 = obj.positionInROI;
                    SL_Pe = obj.PeInROI;
                    nSL = length(SL2);
                    colors = jet(Num);
                    if isempty(configuration.mask)
                        configuration.mask = Mask(configuration.pathMask,configuration.isMaskFilled,configuration.xRange,configuration.yRange,configuration.zRange);
                        if configuration.do_resize
                            configuration.mask = configuration.mask.resize(configuration.sizeFactor);
                        end

                        if configuration.dilate>0
                            configuration.mask = configuration.mask.dilate(configuration.dilate,configuration.dilateDim);
                        end
                    end
                    for ind = 1:10:nSL
                        SL_tmp = SL2{ind};
                        SL_Pe_tmp = SL_Pe{ind};
                        SL_Pe_tmp(SL_Pe_tmp>Pemax) = Pemax;
                        SL_Pe_rk = round(SL_Pe_tmp/Pemax*Num);
                        SL_Pe_rk(SL_Pe_rk<1) = 1;
                        hlines = patch(app.Axes2,[SL_tmp(:,2);NaN],[SL_tmp(:,1);NaN],[SL_tmp(:,3);NaN],[1,1,1]);
                        set(hlines,'FaceVertexCData',[colors(SL_Pe_rk,:);colors(SL_Pe_rk(end),:)],'EdgeColor','flat','FaceColor','none');
                    end
                    %
                    hold(app.Axes2,"on")
                    [x, y, z] = meshgrid(1:configuration.trueSize(2), 1:configuration.trueSize(1), 1:configuration.trueSize(3));
                    mskfv = isosurface(x,y,z,configuration.mask.contents,0.5);
                    mskp = patch(app.Axes2,mskfv);
                    mskp.FaceColor = [.37,.37,.37];
                    mskp.FaceAlpha= 0.1;
                    mskp.EdgeColor = [.37,.37,.37];
                    mskp.EdgeAlpha= 0;
                    view(app.Axes2,[242.1011   14.4475])
                    xticks(app.Axes2,0:5:configuration.trueSize(1))
                    yticks(app.Axes2,0:5:configuration.trueSize(2))
                    zticks(app.Axes2,0:5:configuration.trueSize(3));
                    ax = app.Axes2; ax.FontSize = 10;
                    xlabel(app.Axes2,'x-axis','FontSize',18)
                    ylabel(app.Axes2,'y-axis','FontSize',18)
                    zlabel(app.Axes2,'z-axis','FontSize',18)
                    set(get(app.Axes2,'YLabel'),'Rotation',10);%xtickangle(20);
                    set(get(app.Axes2,'XLabel'),'Rotation',-30,'VerticalAlignment','middle');
                    set(app.Axes2,'Color',[1,1,1])
                    %set(gcf,'unit','normalized','position',[0.5152 0.2790 0.2943 0.3605],'Color',[1,1,1]), set(gcf, 'InvertHardcopy', 'off')
                    grid(app.Axes2,"on") 
                    axis(app.Axes2,"image")
                    xlim(app.Axes2,[0 configuration.trueSize(1)])
                    ylim(app.Axes2,[0 configuration.trueSize(2)])
                    zlim(app.Axes2,[0 configuration.trueSize(3)])
                    colormap(app.Axes2,'jet') 
                    grid(app.Axes2,"on")

                    cb = colorbar(app.Axes2);
                    cb.Ticks = linspace(0, 1, 6);
                    cb.TickLabels = num2cell(round((0:Pemax/5:Pemax)));
                    cb.FontSize = 8;
                    cb.Label.String = '\it{Pe}';
                    %text(1,-3,25,'\it{Pe}','Rotation',90,'FontSize',25);
                    title(app.Axes2,'pelines')
                    xlim(app.Axes2,[0 configuration.trueSize(1)])
                    ylim(app.Axes2,[0 configuration.trueSize(2)])
                    zlim(app.Axes2,[0 configuration.trueSize(3)])
                elseif strcmp(app.dbVisualize.Value,"Displacement vectors")
                    configuration = Configuration3D(app.jsonFileName);
                    load(sprintf('%s/pathlines.mat',configuration.pathOutput));
                    if isempty(configuration.mask)
                        configuration.mask = Mask(configuration.pathMask,configuration.isMaskFilled,configuration.xRange,configuration.yRange,configuration.zRange);
                        if configuration.do_resize
                            configuration.mask = configuration.mask.resize(configuration.sizeFactor);
                        end

                        if configuration.dilate>0
                            configuration.mask = configuration.mask.dilate(configuration.dilate,configuration.dilateDim);
                        end
                    end
                    %% velocity flux vector
                    strid = 10;
                    magnify = 1;
                    cla(app.Axes2,'reset')
                    hold(app.Axes2,"off")
                    
                    axis(app.Axes2)
                    [x, y, z] = meshgrid(1:configuration.trueSize(2), 1:configuration.trueSize(1), 1:configuration.trueSize(3));
                    mskfv = isosurface(x,y,z,configuration.mask.contents,0.5);
                    mskp = patch(app.Axes2,mskfv);
                    mskp.FaceColor = [.17,.17,.17];
                    mskp.FaceAlpha= 0.031;
                    mskp.EdgeColor = [.17,.17,.17];
                    mskp.EdgeAlpha= 0;
                    mskp.DisplayName = 'mask';

                    grid(app.Axes2,"on")
                    axis(app.Axes2,"image")
                    hold(app.Axes2,"on")
                    q = quiver3(app.Axes2,obj.startPointsInROI(1:strid:end,2),obj.startPointsInROI(1:strid:end,1),obj.startPointsInROI(1:strid:end,3),obj.displacementInROI(1:strid:end,2)*magnify,obj.displacementInROI(1:strid:end,1)*magnify,obj.displacementInROI(1:strid:end,3)*magnify,...
                        'color','r','LineWidth',1.5,'MaxHeadSize',0.5,'AutoScale','off','DisplayName','flux vectors');
                    %title(sprintf('Velocity Flux Vectors'),'FontSize',20, 'Interpreter', 'none'),
                    view(app.Axes2,[242.1011   14.4475])
                    ax = app.Axes2; ax.FontSize = 10;
                    xlabel(app.Axes2,'x-axis','FontSize',18)
                    ylabel(app.Axes2,'y-axis','FontSize',18)
                    zlabel(app.Axes2,'z-axis','FontSize',18)
                    set(get(app.Axes2,'YLabel'),'Rotation',10);%xtickangle(20);
                    set(get(app.Axes2,'XLabel'),'Rotation',-30,'VerticalAlignment','middle');
                    set(app.Axes2,'Color',[1,1,1])
                    %set(gcf,'unit','normalized','position',[0.4808 0.3259 0.2937 0.3625],'Color',[1,1,1]), set(gcf, 'InvertHardcopy', 'off')
                    grid(app.Axes2,"on")
                    axis(app.Axes2,"image");
                    xticks(app.Axes2,0:5:configuration.trueSize(1)); yticks(app.Axes2,0:5:configuration.trueSize(2)); zticks(app.Axes2,0:5:configuration.trueSize(3));
                    %// Compute the magnitude of the vectors
                    mags = sqrt(sum(cat(2, q.UData(:), q.VData(:), ...
                        reshape(q.WData, numel(q.UData), [])).^2, 2));

                    %// Get the current colormap
                    currentColormap = colormap(app.Axes2,jet);
                    [~, ~, ind] = histcounts(mags, size(currentColormap, 1));
                    cmap = uint8(ind2rgb(ind(:), currentColormap) * 255);
                    cmap(:,:,4) = 255;
                    cmap = permute(repmat(cmap, [1 3 1]), [2 1 3]);
                    set(q.Head, ...
                        'ColorBinding', 'interpolated', ...
                        'ColorData', reshape(cmap(1:3,:,:), [], 4).');
                    set(q.Tail, ...
                        'ColorBinding', 'interpolated', ...
                        'ColorData', reshape(cmap(1:2,:,:), [], 4).');

                    view(app.Axes2,[242.1011   14.4475])
                    set(app.Axes2,'Color',[1,1,1])
                    %set(gcf,'unit','normalized','position',[0.5152 0.2790 0.2943 0.3605],'Color',[1,1,1]), set(gcf, 'InvertHardcopy', 'off')
                    %colorbar('FontSize',18),
                    grid(app.Axes2,"on")
                    xlim(app.Axes2,[0 configuration.trueSize(1)])
                    ylim(app.Axes2,[0 configuration.trueSize(2)])
                    zlim(app.Axes2,[0 configuration.trueSize(3)])

                    cb = colorbar(app.Axes2);
                    cb.Ticks = linspace(0, 800, 6);
                    cb.TickLabels = num2cell(0:160:800);
                    cb.FontSize = 8;
                    cb.Label.String = 'distance of movement (a.u.)';
                    title(app.Axes2,'velocity flux vector')
                elseif strcmp(app.dbVisualize.Value,"Eulerian source")
                    configuration = Configuration3D(app.jsonFileName);
                    if ~configuration.addSource
                        disp('rOMT does not have relative source results');
                    end
                    load(sprintf('%s/eulerian.mat',configuration.pathOutput));
                    cla(app.Axes2,'reset')
                    hold(app.Axes2,"off")
                    % Eul r
                    x = 1:configuration.trueSize(1);
                    y = 1:configuration.trueSize(2);
                    z = 1:configuration.trueSize(3);
                    src1 = obj.source(abs(obj.source)>0);
                    maxset = maxk(src1(:),round(0.05*length(src1(:))));
                    srcMax = maxset(end);
                    app.sFrame.Limits = [1,obj.nData];
                    app.sFrame.Value = 1;

                    i = 1;
                    axis(app.Axes2)
                    tmp = obj.source(:,:,:,i);
                    tmp(tmp==0) = NaN;
                    hs=slice(y,x,z,tmp,y,x,z);
                    set(hs,'EdgeColor','none','FaceColor','interp','FaceAlpha',0.04);
                    alpha('color'),alphamap(app.Axes2,[linspace(0,0.12,100)])
                    grid(app.Axes2,"off")
                    box(app.Axes2,"off")
                    axis(app.Axes2,"image")
                    xticks(app.Axes2,0:10:configuration.trueSize(1)); yticks(app.Axes2,0:5:configuration.trueSize(2)); zticks(app.Axes2,0:5:configuration.trueSize(3));
                    ax = app.Axes2; ax.FontSize = 10;
                    xlabel(app.Axes2,'x-axis','FontSize',18)
                    ylabel(app.Axes2,'y-axis','FontSize',18)
                    zlabel(app.Axes2,'z-axis','FontSize',18)
                    set(get(app.Axes2,'YLabel'),'Rotation',10);%xtickangle(20);
                    set(get(app.Axes2,'XLabel'),'Rotation',-30,'VerticalAlignment','middle');
                    clim(app.Axes2,[-srcMax,srcMax])
                    colormap(app.Axes2,bluewhitered_drdt)
                    view(app.Axes2,[242.1011   14.4475])
                    set(app.Axes2,'Color',[1,1,1])
                    %set(gcf,'unit','normalized','position',[0.5152 0.2790 0.2943 0.3605],'Color',[1,1,1]), set(gcf, 'InvertHardcopy', 'off')
                    grid(app.Axes2,"on")
                    xlim(app.Axes2,[0 configuration.trueSize(1)])
                    ylim(app.Axes2,[0 configuration.trueSize(2)])
                    zlim(app.Axes2,[0 configuration.trueSize(3)])

                    cb = colorbar(app.Axes2);
                    cb.Ticks = linspace(-srcMax, srcMax, 5);
                    cb.TickLabels = num2cell(round((-srcMax:srcMax/2:srcMax)*100)/100);
                    cb.FontSize = 8;
                    cb.Label.String = 'r (a.u.)';
                    title(app.Axes2,sprintf('Eulerian influx/clearance rate map E%d -> E%d',configuration.timeInitial+(i-1)*configuration.timeJump,configuration.timeInitial+i*configuration.timeJump));
                elseif strcmp(app.dbVisualize.Value,"Eulerian speed")
                    configuration = Configuration3D(app.jsonFileName);
                    load(sprintf('%s/eulerian.mat',configuration.pathOutput));
                    cla(app.Axes2,'reset')
                    hold(app.Axes2,"off")
                    % Eul speed
                    x = 1:configuration.trueSize(1);
                    y = 1:configuration.trueSize(2);
                    z = 1:configuration.trueSize(3);
                    speed1 = obj.speed(obj.speed>0);
                    maxset = maxk(speed1(:),round(0.05*length(speed1(:))));
                    spdMax = maxset(end);
                    app.sFrame.Limits = [1,obj.nData];
                    app.sFrame.Value = 1;

                    i = 1;
                    axis(app.Axes2)
                    tmp = obj.speed(:,:,:,i);
                    hs=slice(app.Axes2,y,x,z,tmp,y,x,z);
                    set(hs,'EdgeColor','none','FaceColor','interp','FaceAlpha',0.04);
                    alpha(app.Axes2,'color')
                    alphamap(app.Axes2,linspace(0,1,100))
                    grid(app.Axes2,"off")
                    box(app.Axes2,"off")
                    axis(app.Axes2,"image")
                    xticks(app.Axes2,0:10:configuration.trueSize(1)); yticks(app.Axes2,0:5:configuration.trueSize(2)); zticks(app.Axes2,0:5:configuration.trueSize(3));
                    ax = app.Axes2; ax.FontSize = 10;
                    xlabel(app.Axes2,'x-axis','FontSize',18)
                    ylabel(app.Axes2,'y-axis','FontSize',18)
                    zlabel(app.Axes2,'z-axis','FontSize',18)
                    set(get(app.Axes2,'YLabel'),'Rotation',10);%xtickangle(20);
                    set(get(app.Axes2,'XLabel'),'Rotation',-30,'VerticalAlignment','middle');
                    colormap(app.Axes2,jet)
                    clim(app.Axes2,[0,spdMax])
                    %caxis(app.Axes2,[0,spdMax])
                    view(app.Axes2,[242.1011   14.4475])
                    set(app.Axes2,'Color',[1,1,1])
                    %set(gcf,'unit','normalized','position',[0.5152 0.2790 0.2943 0.3605],'Color',[1,1,1]), set(gcf, 'InvertHardcopy', 'off')
                    grid(app.Axes2,"on")
                    xlim(app.Axes2,[0 configuration.trueSize(1)])
                    ylim(app.Axes2,[0 configuration.trueSize(2)])
                    zlim(app.Axes2,[0 configuration.trueSize(3)])

                    cb = colorbar(app.Axes2);
                    cb.Ticks = linspace(0, 1, 6);
                    cb.TickLabels = num2cell(round((0:spdMax/5:spdMax)*100)/100);
                    cb.FontSize = 8;
                    cb.Label.String = 'speed (a.u.)';
                    title(app.Axes2,sprintf('Eulerian Speed map E%d -> E%d',configuration.timeInitial+(i-1)*configuration.timeJump,configuration.timeInitial+i*configuration.timeJump));


                end
            % catch
            %     showimage(app)
            % end


        end

        % Value changed function: sFrame
        function sFrameValueChanged(app, event)
            try
                if strcmp(app.dbVisualize.Value,"Eulerian source")
                    configuration = Configuration3D(app.jsonFileName);
                    if ~configuration.addSource
                        disp('rOMT does not have relative source results');
                    end
                    load(sprintf('%s/eulerian.mat',configuration.pathOutput));
                    cla(app.Axes2,'reset')
                    hold(app.Axes2,"off")
                    % Eul r
                    x = 1:configuration.trueSize(1);
                    y = 1:configuration.trueSize(2);
                    z = 1:configuration.trueSize(3);
                    src1 = obj.source(abs(obj.source)>0);
                    maxset = maxk(src1(:),round(0.05*length(src1(:))));
                    srcMax = maxset(end);

                    i = round(app.sFrame.Value);
                    axis(app.Axes2)
                    tmp = obj.source(:,:,:,i);
                    tmp(tmp==0) = NaN;
                    hs=slice(y,x,z,tmp,y,x,z);
                    set(hs,'EdgeColor','none','FaceColor','interp','FaceAlpha',0.04);
                    alpha('color'),alphamap(app.Axes2,[linspace(0,0.12,100)])
                    grid(app.Axes2,"off")
                    box(app.Axes2,"off")
                    axis(app.Axes2,"image")
                    xticks(app.Axes2,0:10:configuration.trueSize(1)); yticks(app.Axes2,0:5:configuration.trueSize(2)); zticks(app.Axes2,0:5:configuration.trueSize(3));
                    ax = app.Axes2; ax.FontSize = 10;
                    xlabel(app.Axes2,'x-axis','FontSize',18)
                    ylabel(app.Axes2,'y-axis','FontSize',18)
                    zlabel(app.Axes2,'z-axis','FontSize',18)
                    set(get(app.Axes2,'YLabel'),'Rotation',10);%xtickangle(20);
                    set(get(app.Axes2,'XLabel'),'Rotation',-30,'VerticalAlignment','middle');
                    clim(app.Axes2,[-srcMax,srcMax])
                    colormap(app.Axes2,bluewhitered_drdt)
                    view(app.Axes2,[242.1011   14.4475])
                    set(app.Axes2,'Color',[1,1,1])
                    %set(gcf,'unit','normalized','position',[0.5152 0.2790 0.2943 0.3605],'Color',[1,1,1]), set(gcf, 'InvertHardcopy', 'off')
                    grid(app.Axes2,"on")
                    xlim(app.Axes2,[0 configuration.trueSize(1)])
                    ylim(app.Axes2,[0 configuration.trueSize(2)])
                    zlim(app.Axes2,[0 configuration.trueSize(3)])

                    cb = colorbar(app.Axes2);
                    cb.Ticks = linspace(-srcMax, srcMax, 5);
                    cb.TickLabels = num2cell(round((-srcMax:srcMax/2:srcMax)*100)/100);
                    cb.FontSize = 8;
                    cb.Label.String = 'r (a.u.)';
                    title(app.Axes2,sprintf('Eulerian influx/clearance rate map E%d -> E%d',configuration.timeInitial+(i-1)*configuration.timeJump,configuration.timeInitial+i*configuration.timeJump));
                elseif strcmp(app.dbVisualize.Value,"Eulerian speed")
                    configuration = Configuration3D(app.jsonFileName);
                    load(sprintf('%s/eulerian.mat',configuration.pathOutput));
                    cla(app.Axes2,'reset')
                    hold(app.Axes2,"off")
                    % Eul speed
                    x = 1:configuration.trueSize(1);
                    y = 1:configuration.trueSize(2);
                    z = 1:configuration.trueSize(3);
                    speed1 = obj.speed(obj.speed>0);
                    maxset = maxk(speed1(:),round(0.05*length(speed1(:))));
                    spdMax = maxset(end);

                    i = round(app.sFrame.Value);
                    axis(app.Axes2)
                    tmp = obj.speed(:,:,:,i);
                    hs=slice(app.Axes2,y,x,z,tmp,y,x,z);
                    set(hs,'EdgeColor','none','FaceColor','interp','FaceAlpha',0.04);
                    alpha(app.Axes2,'color')
                    alphamap(app.Axes2,linspace(0,1,100))
                    grid(app.Axes2,"off")
                    box(app.Axes2,"off")
                    axis(app.Axes2,"image")
                    xticks(app.Axes2,0:10:configuration.trueSize(1)); yticks(app.Axes2,0:5:configuration.trueSize(2)); zticks(app.Axes2,0:5:configuration.trueSize(3));
                    ax = app.Axes2; ax.FontSize = 10;
                    xlabel(app.Axes2,'x-axis','FontSize',18)
                    ylabel(app.Axes2,'y-axis','FontSize',18)
                    zlabel(app.Axes2,'z-axis','FontSize',18)
                    set(get(app.Axes2,'YLabel'),'Rotation',10);%xtickangle(20);
                    set(get(app.Axes2,'XLabel'),'Rotation',-30,'VerticalAlignment','middle');
                    colormap(app.Axes2,jet)
                    clim(app.Axes2,[0,spdMax])
                    view(app.Axes2,[242.1011   14.4475])
                    set(app.Axes2,'Color',[1,1,1])
                    %set(gcf,'unit','normalized','position',[0.5152 0.2790 0.2943 0.3605],'Color',[1,1,1]), set(gcf, 'InvertHardcopy', 'off')
                    grid(app.Axes2,"on")
                    xlim(app.Axes2,[0 configuration.trueSize(1)])
                    ylim(app.Axes2,[0 configuration.trueSize(2)])
                    zlim(app.Axes2,[0 configuration.trueSize(3)])

                    cb = colorbar(app.Axes2);
                    cb.Ticks = linspace(0, 1, 6);
                    cb.TickLabels = num2cell(round((0:spdMax/5:spdMax)*100)/100);
                    cb.FontSize = 8;
                    cb.Label.String = 'speed (a.u.)';
                    title(app.Axes2,sprintf('Eulerian Speed map E%d -> E%d',configuration.timeInitial+(i-1)*configuration.timeJump,configuration.timeInitial+i*configuration.timeJump));

                end

            catch
                showimage(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Axes1 = uiaxes(app.UIFigure);
            title(app.Axes1, 'Input')
            app.Axes1.XTick = [];
            app.Axes1.XTickLabel = {};
            app.Axes1.YTick = [];
            app.Axes1.YTickLabel = {};
            app.Axes1.ZTick = [];
            app.Axes1.ZTickLabel = '';
            app.Axes2 = uiaxes(app.UIFigure);
            title(app.Axes2, 'Output')
            app.Axes2.XTick = [];
            app.Axes2.XTickLabel = {};
            app.Axes2.YTick = [];
            app.Axes2.YTickLabel = {};
            app.Axes2.ZTick = [];
            app.Axes2.ZTickLabel = '';
            app.labelZ = uilabel(app.UIFigure);
            app.labelZ.Text = 'Z=1';
            app.labelT = uilabel(app.UIFigure);
            app.labelT.Text = 'T=1';
            app.sZ = uislider(app.UIFigure);
            app.sZ.ValueChangedFcn = createCallbackFcn(app, @sZValueChanged, true);
            app.sZ.Value = 1;
            app.sT = uislider(app.UIFigure);
            app.sT.ValueChangedFcn = createCallbackFcn(app, @sTValueChanged, true);
            app.sT.Value = 1;
            app.OrientationDropDownLabel = uilabel(app.UIFigure);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.dbOrientation = uidropdown(app.UIFigure);
            app.dbOrientation.Items = {'XY', 'XZ', 'YZ'};
            app.dbOrientation.ValueChangedFcn = createCallbackFcn(app, @dbOrientationValueChanged, true);
            app.dbOrientation.Value = 'XY';
            app.sMin = uislider(app.UIFigure);
            app.sMin.ValueChangedFcn = createCallbackFcn(app, @sMinValueChanged, true);
            app.sMax = uislider(app.UIFigure);
            app.sMax.ValueChangedFcn = createCallbackFcn(app, @sMaxValueChanged, true);
            app.sMax.Value = 100;
            app.ContrastLabel = uilabel(app.UIFigure);
            app.ContrastLabel.Text = 'Contrast';
            app.FilteringDropDownLabel = uilabel(app.UIFigure);
            app.FilteringDropDownLabel.Text = 'Filtering';
            app.dbFiltering = uidropdown(app.UIFigure);
            app.dbFiltering.Items = {'None', 'Median', 'Adjust'};
            app.dbFiltering.ValueChangedFcn = createCallbackFcn(app, @dbFilteringValueChanged, true);
            app.dbFiltering.Value = 'None';
            app.PreprocessingDropDownLabel = uilabel(app.UIFigure);
            app.PreprocessingDropDownLabel.Text = 'Preprocessing';
            app.dbPreprocessing = uidropdown(app.UIFigure);
            app.dbPreprocessing.Items = {'None', 'KAPPA5', 'NLM', 'Wavelets'};
            app.dbPreprocessing.ValueChangedFcn = createCallbackFcn(app, @dbPreprocessingValueChanged, true);
            app.dbPreprocessing.Value = 'None';
            app.P1Label = uilabel(app.UIFigure);
            app.P1Label.Text = 'P1';
            app.sP1 = uislider(app.UIFigure);
            app.P2Label = uilabel(app.UIFigure);
            app.P2Label.Text = 'P2';
            app.sP2 = uislider(app.UIFigure);
            app.FlowcalculationDropDownLabel = uilabel(app.UIFigure);
            app.FlowcalculationDropDownLabel.Text = 'Flow calculation';
            app.FlowcalculationDropDown = uidropdown(app.UIFigure);
            app.FlowcalculationDropDown.Items = {'rOMT', 'urOMT'};
            app.FlowcalculationDropDown.Value = 'rOMT';
            app.AlphaLabel = uilabel(app.UIFigure);
            app.AlphaLabel.Text = 'Alpha';
            app.sAlpha = uislider(app.UIFigure);
            app.sAlpha.Limits = [0 100000];
            app.sAlpha.Value = 10000;
            app.pbProcess = uibutton(app.UIFigure, 'push');
            app.pbProcess.ButtonPushedFcn = createCallbackFcn(app, @pbProcessButtonPushed, true);
            app.pbProcess.Text = 'Process';
            app.Parameter1Label = uilabel(app.UIFigure);
            app.Parameter1Label.Text = 'Parameter1';
            app.sParameter1 = uislider(app.UIFigure);
            app.sParameter1.ValueChangedFcn = createCallbackFcn(app, @sParameter1ValueChanged, true);
            app.Parameter2Label = uilabel(app.UIFigure);
            app.Parameter2Label.Text = 'Parameter2';
            app.sParameter2 = uislider(app.UIFigure);
            app.sParameter2.ValueChangedFcn = createCallbackFcn(app, @sParameter2ValueChanged, true);
            app.pbLoadMask = uibutton(app.UIFigure, 'push');
            app.pbLoadMask.ButtonPushedFcn = createCallbackFcn(app, @pbLoadMaskButtonPushed, true);
            app.pbLoadMask.Text = 'Load Mask';
            app.P4Label = uilabel(app.UIFigure);
            app.P4Label.Text = 'Fmin';
            app.sFmin = uislider(app.UIFigure);
            app.sFmin.ValueChangedFcn = createCallbackFcn(app, @sFminValueChanged, true);
            app.P4Label_2 = uilabel(app.UIFigure);
            app.P4Label_2.Text = 'Fmax';
            app.sFmax = uislider(app.UIFigure);
            app.sFmax.ValueChangedFcn = createCallbackFcn(app, @sFmaxValueChanged, true);
            app.pbLoadVolume = uibutton(app.UIFigure, 'push');
            app.pbLoadVolume.ButtonPushedFcn = createCallbackFcn(app, @pbLoadVolumeButtonPushed, true);
            app.pbLoadVolume.Text = 'Load Volume';
            app.OutputFolderEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder:';
            app.eOutputFolder = uieditfield(app.UIFigure, 'text');
            app.pbBrowse = uibutton(app.UIFigure, 'push');
            app.pbBrowse.ButtonPushedFcn = createCallbackFcn(app, @pbBrowseButtonPushed, true);
            app.pbBrowse.Text = 'Browse';
            app.pbPreprocess = uibutton(app.UIFigure, 'push');
            app.pbPreprocess.ButtonPushedFcn = createCallbackFcn(app, @pbPreprocessButtonPushed, true);
            app.pbPreprocess.Text = 'Preprocess';
            app.VisualizeDropDownLabel = uilabel(app.UIFigure);
            app.VisualizeDropDownLabel.Text = 'Visualize';
            app.dbVisualize = uidropdown(app.UIFigure);
            app.dbVisualize.Items = {'Preprocessed', 'Speed', 'Path lines', 'Peclet numbers', 'Displacement vectors', 'Eulerian source', 'Eulerian speed'};
            app.dbVisualize.ValueChangedFcn = createCallbackFcn(app, @dbVisualizeValueChanged, true);
            app.dbVisualize.Value = 'Preprocessed';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.sFrame = uislider(app.UIFigure);
            app.sFrame.ValueChangedFcn = createCallbackFcn(app, @sFrameValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rOMT_tracker_v03

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end